classdef DarkFocusGUI_single < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DarkFocusUIFigure      matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Slider_2               matlab.ui.control.Slider
        DistancefrompropagationplaneEditField  matlab.ui.control.NumericEditField
        DistancefrompropagationplaneEditFieldLabel  matlab.ui.control.Label
        mLabel_2               matlab.ui.control.Label
        RestorepositionButton  matlab.ui.control.Button
        ExportpositionsButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2_2              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fC % focusCurve
        opts % options
        DarkVolume % darkvolume
        propRange % propagation range
        dispRng % DarkVolume range
    end
    
    methods (Access = private)
        
        function displayPlot(app,fC,centrPos)
            
            plot(app.propRange,fC','*-','LineWidth',2,'Parent',app.UIAxes)
            hold(app.UIAxes, 'on')
            plot([centrPos,centrPos],[0, 1],'Parent',app.UIAxes)
            hold(app.UIAxes, 'off')
            
            if size(fC,1)==1
                legend(app.UIAxes, 'DarkFocus','Previewed position')
            elseif size(fC,1)==3
                legend(app.UIAxes, 'DarkFocus','Tamura','Dubois',...
                    'Previewed position')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fC, opts, DarkVolume)
            app.fC = fC;
            app.opts = opts;
            app.DarkVolume = DarkVolume;
            app.dispRng = [min(DarkVolume(:)),max(DarkVolume(:))];
            
            app.UIAxes.XLim = [opts.distRange(1),opts.distRange(2)];
            app.UIAxes.YLim = [0,1];
            
            pRange = opts.distRange(1):opts.distStep:opts.distRange(2);
            app.propRange = pRange;
            [~,centrPosN] = max(fC(1,:));
            centrPos = pRange(centrPosN);
            
            displayPlot(app,fC,centrPos);
            
            app.Slider_2.Limits = [pRange(1),pRange(end)];
            app.Slider_2.Value = centrPos;
            
            app.DistancefrompropagationplaneEditField.Value = centrPos;
            
            imagesc(DarkVolume(:,:,centrPosN),'Parent',app.UIAxes2_2)
            app.UIAxes2_2.CLim = app.dispRng;
            colormap(app.UIAxes2_2,'gray')
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            p = app.DistancefrompropagationplaneEditField.Value;
            [~,m_b] = min(abs(app.propRange - p));
            centrPos = event.Value;
            [~,m] = min(abs(app.propRange - centrPos));
            if m~=m_b
                centrPos = app.propRange(m);
                app.DistancefrompropagationplaneEditField.Value = centrPos;
                displayPlot(app,app.fC,centrPos);
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            p = app.Slider_2.Value;
            [~,m] = min(abs(app.propRange - p));
            centrPos = app.propRange(m);
            app.Slider_2.Value = centrPos;
            app.DistancefrompropagationplaneEditField.Value = centrPos;
            displayPlot(app,app.fC,centrPos);
            imagesc(app.DarkVolume(:,:,m),'Parent',app.UIAxes2_2)
            app.UIAxes2_2.CLim = app.dispRng;
        end

        % Value changed function: 
        % DistancefrompropagationplaneEditField
        function DistancefrompropagationplaneEditFieldValueChanged(app, event)
            p = app.DistancefrompropagationplaneEditField.Value;
            [~,m] = min(abs(app.propRange - p));
            centrPos = app.propRange(m);
            app.Slider_2.Value = centrPos;
            app.DistancefrompropagationplaneEditField.Value = centrPos;
            displayPlot(app,app.fC,centrPos);
            imagesc(app.DarkVolume(:,:,m),'Parent',app.UIAxes2_2)
            app.UIAxes2_2.CLim = app.dispRng;
        end

        % Button pushed function: RestorepositionButton
        function RestorepositionButtonPushed(app, event)
            [~,centrPosN] = max(app.fC(1,:));
            centrPos = app.propRange(centrPosN);
            app.Slider_2.Value = centrPos;
            app.DistancefrompropagationplaneEditField.Value = centrPos;
            displayPlot(app,app.fC,centrPos);
            imagesc(app.DarkVolume(:,:,centrPosN),'Parent',app.UIAxes2_2)
            app.UIAxes2_2.CLim = app.dispRng;
        end

        % Button pushed function: ExportpositionsButton
        function ExportpositionsButtonPushed(app, event)
            z_new = app.DistancefrompropagationplaneEditField.Value;
            assignin('base','z_new',z_new);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DarkFocusUIFigure = uifigure('Visible', 'off');
            app.DarkFocusUIFigure.Name = 'DarkFocus';
            app.GridLayout = uigridlayout(app.DarkFocusUIFigure);
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.DistancefrompropagationplaneEditField = uieditfield(app.GridLayout, 'numeric');
            app.DistancefrompropagationplaneEditField.ValueChangedFcn = createCallbackFcn(app, @DistancefrompropagationplaneEditFieldValueChanged, true);
            app.DistancefrompropagationplaneEditFieldLabel = uilabel(app.GridLayout);
            app.DistancefrompropagationplaneEditFieldLabel.Text = 'Distance from propagation plane';
            app.mLabel_2 = uilabel(app.GridLayout);
            app.mLabel_2.Text = 'µm';
            app.RestorepositionButton = uibutton(app.GridLayout, 'push');
            app.RestorepositionButton.ButtonPushedFcn = createCallbackFcn(app, @RestorepositionButtonPushed, true);
            app.RestorepositionButton.Text = 'Restore position';
            app.ExportpositionsButton = uibutton(app.GridLayout, 'push');
            app.ExportpositionsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportpositionsButtonPushed, true);
            app.ExportpositionsButton.Text = 'Export current position to workspace as: ''z_new'' variable';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Focus curve')
            xlabel(app.UIAxes, 'Distance from the propagation plane [µm]')
            ylabel(app.UIAxes, 'Focus curve normalized value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2_2 = uiaxes(app.GridLayout);
            title(app.UIAxes2_2, 'Preview reconstructed image')
            zlabel(app.UIAxes2_2, 'Z')
            % Show the figure after all components are created
            app.DarkFocusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DarkFocusGUI_single(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DarkFocusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DarkFocusUIFigure)
        end
    end
end