classdef DarkFocusGUI_triple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DarkFocusUIFigure             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Slider                        matlab.ui.control.Slider
        Slider_2                      matlab.ui.control.Slider
        Slider_3                      matlab.ui.control.Slider
        PositionleftEditFieldLabel    matlab.ui.control.Label
        PositionleftEditField         matlab.ui.control.NumericEditField
        PositioncenterEditField       matlab.ui.control.NumericEditField
        PositioncenterEditFieldLabel  matlab.ui.control.Label
        PositionrightEditField        matlab.ui.control.NumericEditField
        PositionrightEditFieldLabel   matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        mLabel_2                      matlab.ui.control.Label
        mLabel_3                      matlab.ui.control.Label
        eButton                       matlab.ui.control.Button
        maxButton                     matlab.ui.control.Button
        eButton_2                     matlab.ui.control.Button
        ExportpositionsButton         matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2_3                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fC % focusCurve
        opts % options
        DarkVolume % darkvolume
        propRange % propagation range
        dispRng % DarkVolume range
    end
    
    methods (Access = private)
        
        function displayPlot(app,fC,leftPos,centrPos,rightPos)
            
            plot(app.propRange,fC','*-','LineWidth',2,'Parent',app.UIAxes)
            hold(app.UIAxes, 'on')
            plot([leftPos,leftPos],[0, 1],[centrPos,centrPos],[0, 1],...
                [rightPos,rightPos],[0, 1],'Parent',app.UIAxes)
            hold(app.UIAxes, 'off')
            
            if size(fC,1)==1
                legend(app.UIAxes, 'DarkFocus','Preview - left',...
                    'Preview - center','Preview - right')
            elseif size(fC,1)==3
                legend(app.UIAxes, 'DarkFocus','Tamura','Dubois',...
                    'Preview - left','Preview - center','Preview - right')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fC, opts, DarkVolume)
            app.fC = fC;
            app.opts = opts;
            app.DarkVolume = DarkVolume;
            app.dispRng = [min(DarkVolume(:)),max(DarkVolume(:))];
            
            app.UIAxes.XLim = [opts.distRange(1),opts.distRange(2)];
            app.UIAxes.YLim = [0,1];
            
            pRange = opts.distRange(1):opts.distStep:opts.distRange(2);
            app.propRange = pRange;
            leftPosN = find(fC(1,:)>0.3679,1);
            leftPos = pRange(leftPosN);
            [~,centrPosN] = max(fC(1,:));
            centrPos = pRange(centrPosN);
            rightPosN = length(pRange)-find(flip(fC(1,:))>0.3679,1)+1;
            rightPos = pRange(rightPosN);
            
            displayPlot(app,fC,leftPos,centrPos,rightPos);
            
            app.Slider.Limits = [pRange(1),pRange(end)];
            app.Slider_2.Limits = [pRange(1),pRange(end)];
            app.Slider_3.Limits = [pRange(1),pRange(end)];
            app.Slider.Value = leftPos;
            app.Slider_2.Value = centrPos;
            app.Slider_3.Value = rightPos;
            
            app.PositionleftEditField.Value = leftPos;
            app.PositioncenterEditField.Value = centrPos;
            app.PositionrightEditField.Value = rightPos;
            
            imagesc(DarkVolume(:,:,leftPosN),'Parent',app.UIAxes2)
            app.UIAxes2.CLim = app.dispRng;
            colormap(app.UIAxes2,'gray')
            imagesc(DarkVolume(:,:,centrPosN),'Parent',app.UIAxes2_2)
            app.UIAxes2_2.CLim = app.dispRng;
            colormap(app.UIAxes2_2,'gray')
            imagesc(DarkVolume(:,:,rightPosN),'Parent',app.UIAxes2_3)
            app.UIAxes2_3.CLim = app.dispRng;
            colormap(app.UIAxes2_3,'gray')
            ax(1) = app.UIAxes2; ax(2) = app.UIAxes2_2;
            ax(3) = app.UIAxes2_3;
            linkaxes(ax)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            p = app.PositionleftEditField.Value;
            [~,m_b] = min(abs(app.propRange - p));
            leftPos = event.Value;
            [~,m] = min(abs(app.propRange - leftPos));
            if m~=m_b
                leftPos = app.propRange(m);
                app.PositionleftEditField.Value = leftPos;
                centrPos = app.PositioncenterEditField.Value;
                rightPos = app.PositionrightEditField.Value;
                displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            p = app.Slider.Value;
            [~,m] = min(abs(app.propRange - p));
            leftPos = app.propRange(m);
            app.Slider.Value = leftPos;
            app.PositionleftEditField.Value = leftPos;
            centrPos = app.PositioncenterEditField.Value;
            rightPos = app.PositionrightEditField.Value;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,m),'Parent',app.UIAxes2)
            app.UIAxes2.CLim = app.dispRng;
        end

        % Value changed function: PositionleftEditField
        function PositionleftEditFieldValueChanged(app, event)
            p = app.PositionleftEditField.Value;
            [~,m] = min(abs(app.propRange - p));
            leftPos = app.propRange(m);
            app.Slider.Value = leftPos;
            app.PositionleftEditField.Value = leftPos;
            centrPos = app.PositioncenterEditField.Value;
            rightPos = app.PositionrightEditField.Value;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,m),'Parent',app.UIAxes2)
            app.UIAxes2.CLim = app.dispRng;
        end

        % Button pushed function: eButton
        function eButtonPushed(app, event)
            leftPosN = find(app.fC(1,:)>0.3679,1);
            leftPos = app.propRange(leftPosN);
            centrPos = app.PositioncenterEditField.Value;
            rightPos = app.PositionrightEditField.Value;
            app.Slider.Value = leftPos;
            app.PositionleftEditField.Value = leftPos;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,leftPosN),'Parent',app.UIAxes2)
            app.UIAxes2.CLim = app.dispRng;
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            p = app.PositioncenterEditField.Value;
            [~,m_b] = min(abs(app.propRange - p));
            centrPos = event.Value;
            [~,m] = min(abs(app.propRange - centrPos));
            if m~=m_b
                centrPos = app.propRange(m);
                app.PositioncenterEditField.Value = centrPos;
                leftPos = app.PositionleftEditField.Value;
                rightPos = app.PositionrightEditField.Value;
                displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            p = app.Slider_2.Value;
            [~,m] = min(abs(app.propRange - p));
            centrPos = app.propRange(m);
            app.Slider_2.Value = centrPos;
            app.PositioncenterEditField.Value = centrPos;
            leftPos = app.PositionleftEditField.Value;
            rightPos = app.PositionrightEditField.Value;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,m),'Parent',app.UIAxes2_2)
            app.UIAxes2_2.CLim = app.dispRng;
        end

        % Value changed function: PositioncenterEditField
        function PositioncenterEditFieldValueChanged(app, event)
            p = app.PositioncenterEditField.Value;
            [~,m] = min(abs(app.propRange - p));
            centrPos = app.propRange(m);
            app.Slider_2.Value = centrPos;
            app.PositioncenterEditField.Value = centrPos;
            leftPos = app.PositionleftEditField.Value;
            rightPos = app.PositionrightEditField.Value;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,m),'Parent',app.UIAxes2_2)
            app.UIAxes2_2.CLim = app.dispRng;
        end

        % Button pushed function: maxButton
        function maxButtonPushed(app, event)
            [~,centrPosN] = max(app.fC(1,:));
            centrPos = app.propRange(centrPosN);
            leftPos = app.PositionleftEditField.Value;
            rightPos = app.PositionrightEditField.Value;
            app.Slider_2.Value = centrPos;
            app.PositioncenterEditField.Value = centrPos;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,centrPosN),'Parent',app.UIAxes2_2)
            app.UIAxes2_2.CLim = app.dispRng;
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            p = app.PositionrightEditField.Value;
            [~,m_b] = min(abs(app.propRange - p));
            rightPos = event.Value;
            [~,m] = min(abs(app.propRange - rightPos));
            if m~=m_b
                rightPos = app.propRange(m);
                app.PositionrightEditField.Value = rightPos;
                leftPos = app.PositionleftEditField.Value;
                centrPos = app.PositioncenterEditField.Value;
                displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            end
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            p = app.Slider_3.Value;
            [~,m] = min(abs(app.propRange - p));
            rightPos = app.propRange(m);
            app.Slider_3.Value = rightPos;
            app.PositionrightEditField.Value = rightPos;
            leftPos = app.PositionleftEditField.Value;
            centrPos = app.PositioncenterEditField.Value;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,m),'Parent',app.UIAxes2_3)
            app.UIAxes2_3.CLim = app.dispRng;
        end

        % Value changed function: PositionrightEditField
        function PositionrightEditFieldValueChanged(app, event)
            p = app.PositionrightEditField.Value;
            [~,m] = min(abs(app.propRange - p));
            rightPos = app.propRange(m);
            app.Slider_3.Value = rightPos;
            app.PositionrightEditField.Value = rightPos;
            leftPos = app.PositionleftEditField.Value;
            centrPos = app.PositioncenterEditField.Value;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,m),'Parent',app.UIAxes2_3)
            app.UIAxes2_3.CLim = app.dispRng;
        end

        % Button pushed function: eButton_2
        function eButton_2Pushed(app, event)
            rightPosN = length(app.propRange) - ...
                find(flip(app.fC(1,:))>0.3679,1);
            rightPos = app.propRange(rightPosN);
            centrPos = app.PositioncenterEditField.Value;
            leftPos = app.PositionleftEditField.Value;
            app.Slider_3.Value = rightPos;
            app.PositionrightEditField.Value = rightPos;
            displayPlot(app,app.fC,leftPos,centrPos,rightPos);
            imagesc(app.DarkVolume(:,:,rightPosN),'Parent',app.UIAxes2_3)
            app.UIAxes2_3.CLim = app.dispRng;
        end

        % Button pushed function: ExportpositionsButton
        function ExportpositionsButtonPushed(app, event)
            rightPos = app.PositionrightEditField.Value;
            centrPos = app.PositioncenterEditField.Value;
            leftPos = app.PositionleftEditField.Value;
            outDF = [leftPos,centrPos,rightPos];
            assignin('base','outDF',outDF);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DarkFocusUIFigure = uifigure('Visible', 'off');
            app.DarkFocusUIFigure.Name = 'DarkFocus';
            app.GridLayout = uigridlayout(app.DarkFocusUIFigure);
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_3 = uislider(app.GridLayout);
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.PositionleftEditFieldLabel = uilabel(app.GridLayout);
            app.PositionleftEditFieldLabel.Text = 'Position - left';
            app.PositionleftEditField = uieditfield(app.GridLayout, 'numeric');
            app.PositionleftEditField.ValueChangedFcn = createCallbackFcn(app, @PositionleftEditFieldValueChanged, true);
            app.PositioncenterEditField = uieditfield(app.GridLayout, 'numeric');
            app.PositioncenterEditField.ValueChangedFcn = createCallbackFcn(app, @PositioncenterEditFieldValueChanged, true);
            app.PositioncenterEditFieldLabel = uilabel(app.GridLayout);
            app.PositioncenterEditFieldLabel.Text = 'Position - center';
            app.PositionrightEditField = uieditfield(app.GridLayout, 'numeric');
            app.PositionrightEditField.ValueChangedFcn = createCallbackFcn(app, @PositionrightEditFieldValueChanged, true);
            app.PositionrightEditFieldLabel = uilabel(app.GridLayout);
            app.PositionrightEditFieldLabel.Text = 'Position - right';
            app.mLabel = uilabel(app.GridLayout);
            app.mLabel.Text = 'µm';
            app.mLabel_2 = uilabel(app.GridLayout);
            app.mLabel_2.Text = 'µm';
            app.mLabel_3 = uilabel(app.GridLayout);
            app.mLabel_3.Text = 'µm';
            app.eButton = uibutton(app.GridLayout, 'push');
            app.eButton.ButtonPushedFcn = createCallbackFcn(app, @eButtonPushed, true);
            app.eButton.Text = '1/e';
            app.maxButton = uibutton(app.GridLayout, 'push');
            app.maxButton.ButtonPushedFcn = createCallbackFcn(app, @maxButtonPushed, true);
            app.maxButton.Text = 'max';
            app.eButton_2 = uibutton(app.GridLayout, 'push');
            app.eButton_2.ButtonPushedFcn = createCallbackFcn(app, @eButton_2Pushed, true);
            app.eButton_2.Text = '1/e';
            app.ExportpositionsButton = uibutton(app.GridLayout, 'push');
            app.ExportpositionsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportpositionsButtonPushed, true);
            app.ExportpositionsButton.Text = 'Export positions to workspace as: outDF = [left, center, right];';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Preview - left')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Focus curve')
            xlabel(app.UIAxes, 'Distance from the propagation plane [µm]')
            ylabel(app.UIAxes, 'Focus curve normalized value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2_2 = uiaxes(app.GridLayout);
            title(app.UIAxes2_2, 'Preview - center')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.GridLayout);
            title(app.UIAxes2_3, 'Preview - right')
            zlabel(app.UIAxes2_3, 'Z')
            % Show the figure after all components are created
            app.DarkFocusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DarkFocusGUI_triple(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DarkFocusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DarkFocusUIFigure)
        end
    end
end